classdef NFL2022Analysis_Final_Version < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        TeamSeasonAnalysisTab      matlab.ui.container.Tab
        TabGroup_2                 matlab.ui.container.TabGroup
        Team1ScheduleTab           matlab.ui.container.Tab
        schedule1Table             matlab.ui.control.Table
        Team2ScheduleTab           matlab.ui.container.Tab
        schedule2Table             matlab.ui.control.Table
        Team2NameLabel             matlab.ui.control.Label
        teamDropDown               matlab.ui.control.DropDown
        ChooseaTeamsLabel          matlab.ui.control.Label
        team2DropDown              matlab.ui.control.DropDown
        TeamNameLabel              matlab.ui.control.Label
        PlotTypesLabel             matlab.ui.control.Label
        TeamButtonGroup            matlab.ui.container.ButtonGroup
        TwoTeamsButton             matlab.ui.control.RadioButton
        OneTeamButton              matlab.ui.control.RadioButton
        PlotTypeButtonGroup        matlab.ui.container.ButtonGroup
        BothButton                 matlab.ui.control.RadioButton
        QBELOButton                matlab.ui.control.RadioButton
        TeamELOButton              matlab.ui.control.RadioButton
        team2Logo                  matlab.ui.control.Image
        TeamLogo                   matlab.ui.control.Image
        ELOAxes                    matlab.ui.control.UIAxes
        WeeklyELORankingsTab       matlab.ui.container.Tab
        ThirdPlaceLabel            matlab.ui.control.Label
        SecondPlaceLabel           matlab.ui.control.Label
        FirstPlaceLabel            matlab.ui.control.Label
        ByeWeekLabel               matlab.ui.control.Label
        Week17NoticeLabel          matlab.ui.control.Label
        TeamNameTable              matlab.ui.control.Table
        ByesList                   matlab.ui.control.ListBox
        TeamsWithByesListBoxLabel  matlab.ui.control.Label
        TeamsDropDown              matlab.ui.control.DropDown
        TeamsDropDownLabel         matlab.ui.control.Label
        PrevButton                 matlab.ui.control.StateButton
        NextButton                 matlab.ui.control.StateButton
        gameWeekEditField          matlab.ui.control.NumericEditField
        GameWeeksSlider            matlab.ui.control.Slider
        GameWeeksSliderLabel       matlab.ui.control.Label
        PlotDescription            matlab.ui.control.Label
        ELOBarPlot                 matlab.ui.control.UIAxes
        GameQualityvsScoringTab    matlab.ui.container.Tab
        NoticeLabel                matlab.ui.control.Label
        ComparetwoCheckBox         matlab.ui.control.CheckBox
        Team2Image                 matlab.ui.control.Image
        Team2Label                 matlab.ui.control.Label
        Team1Label                 matlab.ui.control.Label
        Team1Image                 matlab.ui.control.Image
        SpecificDataType2Label     matlab.ui.control.Label
        SpecificDataType2DropDown  matlab.ui.control.DropDown
        SpecificDataType1Label     matlab.ui.control.Label
        SpecificDataType1DropDown  matlab.ui.control.DropDown
        DataTypeDropDown           matlab.ui.control.DropDown
        DataTypeDropDownLabel      matlab.ui.control.Label
        TimeFrameDropDown          matlab.ui.control.DropDown
        TimeFrameDropDownLabel     matlab.ui.control.Label
        AverageQualitiesAxes       matlab.ui.control.UIAxes
        AverageScoreAxes           matlab.ui.control.UIAxes
        SingleGameAnalysisTab      matlab.ui.container.Tab
        WeekSlider                 matlab.ui.control.Slider
        WeekSliderLabel            matlab.ui.control.Label
        Result2Label               matlab.ui.control.Label
        Result1Label               matlab.ui.control.Label
        Team2NameLabelSingleGame   matlab.ui.control.Label
        Team1NameLabel             matlab.ui.control.Label
        GameResultsLabel           matlab.ui.control.Label
        WeekLabel                  matlab.ui.control.Label
        WeekGameDropDown           matlab.ui.control.DropDown
        WeekDropDown               matlab.ui.control.DropDown
        WeekDropDownLabel          matlab.ui.control.Label
        SingleGameAnalysisLabel    matlab.ui.control.Label
        PieChartAxes               matlab.ui.control.UIAxes
        Team2GameAxes              matlab.ui.control.UIAxes
        Team1GameAxes              matlab.ui.control.UIAxes
        GlossaryTab                matlab.ui.container.Tab
        Label_3                    matlab.ui.control.Label
        WinPercentageLabel         matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        ByeWeekLabel_2             matlab.ui.control.Label
        SourceCitationLabel_2      matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        SourcesLabel               matlab.ui.control.Label
        SourceCitationLabel        matlab.ui.control.Label
        ELORatingLabel             matlab.ui.control.Label
        GlossaryLabel              matlab.ui.control.Label
    end

    
    properties (Access = public)
        NFLData = {};
        Metadata = {};
        ELOValues;
        QB_ELOValues; 
        OGTeamOrder = [""];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Load the NFL Data
            app.NFLData = readcell("NFL_ELO_2022-2023_Season.xlsx");
            [nRow, nCol] = size(app.NFLData);
            % Add a column for game week number
            app.NFLData{1, nCol + 1} = 'Week Number';
            % NFLData{2, nCol + 1} = 1;
            startGameDate = app.NFLData{2,1}; % Start of a game week
            endGameDate = startGameDate + caldays(7); % End of a game week
            gameWeek = 1; % Game week counter

            for iRow = 2:nRow
                if isbetween(app.NFLData{iRow, 1}, startGameDate, endGameDate, 'openright') % tests if the game is within the start and end date variables
                    app.NFLData{iRow, nCol + 1} = gameWeek;
                else % otherwise, move the start date to the end date and increment the week
                    startGameDate = endGameDate;
                    endGameDate = startGameDate + caldays(7);
                    gameWeek = gameWeek + 1;
                    app.NFLData{iRow, nCol + 1} = gameWeek;
                end
                if strcmp(class(app.NFLData{iRow, 4}), 'missing')
                    app.NFLData{iRow, 4} = 'N/A';
                end
                app.NFLData{iRow, 1} = char(app.NFLData{iRow, 1});
            end

            %% Loading Metadata
            app.Metadata = readcell("NFL_Team_Metadata.xlsx");
            [nRowMeta, ~] = size(app.Metadata);

            %% Question 1 Startup Code
            % Put Team Names in The Dropdown
            app.teamDropDown.Items = app.Metadata(1:32, 2)';
            app.team2DropDown.Items = app.Metadata(1:32, 2)';

            plotTeamELO2(app)
            %% Question 2 Startup Code
            % Get the original order for the Team x-axis
            index = 1;
            for codeIndex = 2:nRow
                if app.NFLData{codeIndex, 34} == 1
                    app.OGTeamOrder(index) = app.NFLData{codeIndex, 5};
                    index = index + 1;
                    app.OGTeamOrder(index) = app.NFLData{codeIndex, 6};
                    index = index + 1;
                end
            end
            % Sort this team order
            app.OGTeamOrder = sort(app.OGTeamOrder);
            
            % Put the Team Code and Names into the Team Name Table
            teamNameInfo = [""];
            for codeIndex = 1:length(app.OGTeamOrder)
                teamNameInfo(codeIndex, 1) = string(app.OGTeamOrder(codeIndex));
                for jRow = 1:nRowMeta
                    if strcmp(app.Metadata{jRow, 1}, app.OGTeamOrder(codeIndex))
                        teamNameInfo(codeIndex, 2) = string(app.Metadata{jRow, 2});
                    end
                end
            end
            app.TeamNameTable.Data = teamNameInfo;

            barELOFunction2(app);

            %% Question 3 Startup Code
            % Load Team Names in the dropdown
            app.DataTypeDropDown.Value = "Teams";
            app.SpecificDataType1Label.Text = "Team 1";
            app.SpecificDataType1DropDown.Items = app.Metadata(1:32, 2)';
            plotAverages(app)
            gameAnalysis(app)
        end

        % Value changed function: teamDropDown
        function teamDropDownValueChanged(app, event)
           %% Question 1
           plotTeamELO2(app);
        end

        % Selection changed function: PlotTypeButtonGroup
        function PlotTypeButtonGroupSelectionChanged(app, event)
            %% Question 1
            plotTeamELO2(app);
        end

        % Selection changed function: TeamButtonGroup
        function TeamButtonGroupSelectionChanged(app, event)
            %% Question 1
            teamNumChoice = app.TeamButtonGroup.SelectedObject.Text;
            if strcmp(teamNumChoice, "One Team")
                teamChoice = app.teamDropDown.Value;
                app.TeamNameLabel.Text = teamChoice;
                app.team2DropDown.Visible = 'off';
                app.team2Logo.Visible = 'off';
                app.Team2NameLabel.Visible = 'off';
                app.schedule2Table.Visible = 'off';
            elseif strcmp(teamNumChoice, "Two Teams")
                app.team2DropDown.Visible = 'on';
                app.Team2NameLabel.Visible = 'on';
                app.team2Logo.Visible = 'on';
                app.schedule2Table.Visible = 'on';
            end

            plotTeamELO2(app);
        end

        % Value changed function: team2DropDown
        function team2DropDownValueChanged(app, event)
            %% Question 1
            plotTeamELO2(app);
        end

        % Value changed function: TeamsDropDown
        function TeamsDropDownValueChanged(app, event)
            %% Question 2
            barELOFunction2(app);
        end

        % Value changed function: GameWeeksSlider
        function GameWeeksSliderValueChanged(app, event)
            %% Question 2
            app.gameWeekEditField.Value = round(app.GameWeeksSlider.Value);
            barELOFunction2(app);
        end

        % Value changed function: NextButton
        function NextButtonValueChanged(app, event)
            %% Question 2
            if app.gameWeekEditField.Value ~= 22
                app.gameWeekEditField.Value = app.gameWeekEditField.Value + 1;
                app.GameWeeksSlider.Value = app.gameWeekEditField.Value;
                barELOFunction2(app);
            end
        end

        % Value changed function: PrevButton
        function PrevButtonValueChanged(app, event)
            %% Question 2
            if app.gameWeekEditField.Value ~= 1
            app.gameWeekEditField.Value = app.gameWeekEditField.Value - 1;
            app.GameWeeksSlider.Value = app.gameWeekEditField.Value;
            barELOFunction2(app);
            end
        end

        % Value changed function: gameWeekEditField
        function gameWeekEditFieldValueChanged(app, event)
            %% Question 2
            app.GameWeeksSlider.Value = app.gameWeekEditField.Value;
            barELOFunction2(app);
        end

        % Value changed function: TimeFrameDropDown
        function TimeFrameDropDownValueChanged(app, event)
            % Question 3
            value = app.TimeFrameDropDown.Value;
            plotAverages(app);
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            % Question 3
            value = app.DataTypeDropDown.Value;
            plotAverages(app);
        end

        % Value changed function: ComparetwoCheckBox
        function ComparetwoCheckBoxValueChanged(app, event)
            value = app.ComparetwoCheckBox.Value;
            plotAverages(app);
        end

        % Value changed function: SpecificDataType1DropDown
        function SpecificDataType1DropDownValueChanged(app, event)
            value = app.SpecificDataType1DropDown.Value;
            plotAverages(app)
        end

        % Value changed function: SpecificDataType2DropDown
        function SpecificDataType2DropDownValueChanged(app, event)
            value = app.SpecificDataType2DropDown.Value;
            plotAverages(app)
        end

        % Value changed function: WeekDropDown
        function WeekDropDownValueChanged(app, event)
            % Question 4
            value = split(app.WeekDropDown.Value);
            value = string(value(2));
            app.WeekSlider.Value = str2num(value);
            gameAnalysis(app)
        end

        % Value changed function: WeekGameDropDown
        function WeekGameDropDownValueChanged(app, event)
            value = app.WeekGameDropDown.Value;
            gameAnalysis(app)
        end

        % Value changed function: WeekSlider
        function WeekSliderValueChanged(app, event)
        value = string("Week " + num2str(round(app.WeekSlider.Value)));
        app.WeekDropDown.Value = value;
        gameAnalysis(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TeamSeasonAnalysisTab = uitab(app.TabGroup);
            app.TeamSeasonAnalysisTab.Title = 'Team Season Analysis';
            app.ELOAxes = uiaxes(app.TeamSeasonAnalysisTab);
            title(app.ELOAxes, 'ELO Rating vs Time')
            xlabel(app.ELOAxes, 'Game #')
            ylabel(app.ELOAxes, 'ELO Rating')
            zlabel(app.ELOAxes, 'Z')
            app.TeamLogo = uiimage(app.TeamSeasonAnalysisTab);
            app.team2Logo = uiimage(app.TeamSeasonAnalysisTab);
            app.team2Logo.Visible = 'off';
            app.PlotTypeButtonGroup = uibuttongroup(app.TeamSeasonAnalysisTab);
            app.PlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.TeamELOButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.TeamELOButton.Text = 'Team ELO';
            app.QBELOButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.QBELOButton.Text = 'QB ELO';
            app.BothButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.TeamButtonGroup = uibuttongroup(app.TeamSeasonAnalysisTab);
            app.TeamButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TeamButtonGroupSelectionChanged, true);
            app.OneTeamButton = uiradiobutton(app.TeamButtonGroup);
            app.OneTeamButton.Text = 'One Team';
            app.OneTeamButton.Value = true;
            app.TwoTeamsButton = uiradiobutton(app.TeamButtonGroup);
            app.TwoTeamsButton.Text = 'Two Teams';
            app.PlotTypesLabel = uilabel(app.TeamSeasonAnalysisTab);
            app.PlotTypesLabel.Text = 'Plot Types';
            app.TeamNameLabel = uilabel(app.TeamSeasonAnalysisTab);
            app.TeamNameLabel.Text = 'Team Name';
            app.team2DropDown = uidropdown(app.TeamSeasonAnalysisTab);
            app.team2DropDown.ValueChangedFcn = createCallbackFcn(app, @team2DropDownValueChanged, true);
            app.team2DropDown.Visible = 'off';
            app.ChooseaTeamsLabel = uilabel(app.TeamSeasonAnalysisTab);
            app.ChooseaTeamsLabel.Text = 'Choose a Team(s)';
            app.teamDropDown = uidropdown(app.TeamSeasonAnalysisTab);
            app.teamDropDown.Items = {'Team 1', 'Team 2', 'Team 3', 'Team 4'};
            app.teamDropDown.ValueChangedFcn = createCallbackFcn(app, @teamDropDownValueChanged, true);
            app.teamDropDown.Value = 'Team 1';
            app.Team2NameLabel = uilabel(app.TeamSeasonAnalysisTab);
            app.Team2NameLabel.Visible = 'off';
            app.Team2NameLabel.Text = 'Team Name';
            app.TabGroup_2 = uitabgroup(app.TeamSeasonAnalysisTab);
            app.Team1ScheduleTab = uitab(app.TabGroup_2);
            app.Team1ScheduleTab.Title = 'Team 1 Schedule';
            app.schedule1Table = uitable(app.Team1ScheduleTab);
            app.schedule1Table.ColumnName = {'Game Week #'; 'Date'; 'Quarterback'; 'Opponent'; 'Result'};
            app.schedule1Table.RowName = {};
            app.Team2ScheduleTab = uitab(app.TabGroup_2);
            app.Team2ScheduleTab.Title = 'Team 2 Schedule';
            app.schedule2Table = uitable(app.Team2ScheduleTab);
            app.schedule2Table.ColumnName = {'Game Week #'; 'Date'; 'Quarterback'; 'Opponent'; 'Result'};
            app.schedule2Table.RowName = {};
            app.schedule2Table.Visible = 'off';
            app.WeeklyELORankingsTab = uitab(app.TabGroup);
            app.WeeklyELORankingsTab.Title = 'Weekly ELO Rankings';
            app.ELOBarPlot = uiaxes(app.WeeklyELORankingsTab);
            xlabel(app.ELOBarPlot, 'Team Names')
            ylabel(app.ELOBarPlot, 'ELO Rating')
            zlabel(app.ELOBarPlot, 'Z')
            app.ELOBarPlot.XTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32];
            app.ELOBarPlot.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'};
            app.PlotDescription = uilabel(app.WeeklyELORankingsTab);
            app.PlotDescription.Text = 'Week 1 ELO Rating For All Teams';
            app.GameWeeksSliderLabel = uilabel(app.WeeklyELORankingsTab);
            app.GameWeeksSliderLabel.Text = 'Game Weeks';
            app.GameWeeksSlider = uislider(app.WeeklyELORankingsTab);
            app.GameWeeksSlider.Limits = [1 22];
            app.GameWeeksSlider.MajorTicks = [1 3 5 7 9 11 13 15 17 19 21];
            app.GameWeeksSlider.ValueChangedFcn = createCallbackFcn(app, @GameWeeksSliderValueChanged, true);
            app.GameWeeksSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22];
            app.GameWeeksSlider.Value = 1;
            app.gameWeekEditField = uieditfield(app.WeeklyELORankingsTab, 'numeric');
            app.gameWeekEditField.ValueChangedFcn = createCallbackFcn(app, @gameWeekEditFieldValueChanged, true);
            app.gameWeekEditField.Value = 1;
            app.NextButton = uibutton(app.WeeklyELORankingsTab, 'state');
            app.NextButton.ValueChangedFcn = createCallbackFcn(app, @NextButtonValueChanged, true);
            app.NextButton.Text = 'Next';
            app.PrevButton = uibutton(app.WeeklyELORankingsTab, 'state');
            app.PrevButton.ValueChangedFcn = createCallbackFcn(app, @PrevButtonValueChanged, true);
            app.PrevButton.Text = 'Prev';
            app.TeamsDropDownLabel = uilabel(app.WeeklyELORankingsTab);
            app.TeamsDropDownLabel.Text = 'Teams';
            app.TeamsDropDown = uidropdown(app.WeeklyELORankingsTab);
            app.TeamsDropDown.Items = {'All Teams', 'NFC', 'NFC East', 'NFC West', 'NFC North', 'NFC South', 'AFC', 'AFC West', 'AFC East', 'AFC North', 'AFC South'};
            app.TeamsDropDown.ValueChangedFcn = createCallbackFcn(app, @TeamsDropDownValueChanged, true);
            app.TeamsDropDown.Value = 'All Teams';
            app.TeamsWithByesListBoxLabel = uilabel(app.WeeklyELORankingsTab);
            app.TeamsWithByesListBoxLabel.Text = 'Teams With Byes';
            app.ByesList = uilistbox(app.WeeklyELORankingsTab);
            app.ByesList.Items = {''};
            app.ByesList.Value = '';
            app.TeamNameTable = uitable(app.WeeklyELORankingsTab);
            app.TeamNameTable.ColumnName = {'Code'; 'Team Name'};
            app.TeamNameTable.RowName = {};
            app.Week17NoticeLabel = uilabel(app.WeeklyELORankingsTab);
            app.Week17NoticeLabel.Visible = 'off';
            app.Week17NoticeLabel.Text = 'NOTICE: The Bengals vs Bills Game was cancelled this week';
            app.ByeWeekLabel = uilabel(app.WeeklyELORankingsTab);
            app.ByeWeekLabel.Text = 'Bye Week';
            app.FirstPlaceLabel = uilabel(app.WeeklyELORankingsTab);
            app.FirstPlaceLabel.Text = 'First Place';
            app.SecondPlaceLabel = uilabel(app.WeeklyELORankingsTab);
            app.SecondPlaceLabel.Text = 'Second Place';
            app.ThirdPlaceLabel = uilabel(app.WeeklyELORankingsTab);
            app.ThirdPlaceLabel.Text = 'Third Place';
            app.GameQualityvsScoringTab = uitab(app.TabGroup);
            app.GameQualityvsScoringTab.Title = 'Game Quality vs Scoring';
            app.AverageScoreAxes = uiaxes(app.GameQualityvsScoringTab);
            title(app.AverageScoreAxes, 'Average Total Scores')
            xlabel(app.AverageScoreAxes, 'Week')
            ylabel(app.AverageScoreAxes, 'Average Score (Points)')
            zlabel(app.AverageScoreAxes, 'Z')
            app.AverageQualitiesAxes = uiaxes(app.GameQualityvsScoringTab);
            title(app.AverageQualitiesAxes, 'Average Game Qualities')
            xlabel(app.AverageQualitiesAxes, 'Week')
            ylabel(app.AverageQualitiesAxes, 'Average Game Qualities')
            zlabel(app.AverageQualitiesAxes, 'Z')
            app.TimeFrameDropDownLabel = uilabel(app.GameQualityvsScoringTab);
            app.TimeFrameDropDownLabel.Text = 'Time Frame';
            app.TimeFrameDropDown = uidropdown(app.GameQualityvsScoringTab);
            app.TimeFrameDropDown.Items = {'Regular Season', 'Playoffs', 'Both'};
            app.TimeFrameDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeFrameDropDownValueChanged, true);
            app.TimeFrameDropDown.Value = 'Regular Season';
            app.DataTypeDropDownLabel = uilabel(app.GameQualityvsScoringTab);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.GameQualityvsScoringTab);
            app.DataTypeDropDown.Items = {'All Teams', 'Teams', 'Divisions', 'Conferences'};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = 'All Teams';
            app.SpecificDataType1DropDown = uidropdown(app.GameQualityvsScoringTab);
            app.SpecificDataType1DropDown.Items = {'Teams', 'Division', 'Conference'};
            app.SpecificDataType1DropDown.ValueChangedFcn = createCallbackFcn(app, @SpecificDataType1DropDownValueChanged, true);
            app.SpecificDataType1DropDown.Value = 'Teams';
            app.SpecificDataType1Label = uilabel(app.GameQualityvsScoringTab);
            app.SpecificDataType1Label.Text = 'Team 1';
            app.SpecificDataType2DropDown = uidropdown(app.GameQualityvsScoringTab);
            app.SpecificDataType2DropDown.Items = {'Teams', 'Division', 'Conference'};
            app.SpecificDataType2DropDown.ValueChangedFcn = createCallbackFcn(app, @SpecificDataType2DropDownValueChanged, true);
            app.SpecificDataType2DropDown.Visible = 'off';
            app.SpecificDataType2DropDown.Value = 'Teams';
            app.SpecificDataType2Label = uilabel(app.GameQualityvsScoringTab);
            app.SpecificDataType2Label.Visible = 'off';
            app.SpecificDataType2Label.Text = 'Team 2';
            app.Team1Image = uiimage(app.GameQualityvsScoringTab);
            app.Team1Label = uilabel(app.GameQualityvsScoringTab);
            app.Team1Label.Text = 'Team 1';
            app.Team2Label = uilabel(app.GameQualityvsScoringTab);
            app.Team2Label.Visible = 'off';
            app.Team2Label.Text = 'Team 2';
            app.Team2Image = uiimage(app.GameQualityvsScoringTab);
            app.Team2Image.Visible = 'off';
            app.ComparetwoCheckBox = uicheckbox(app.GameQualityvsScoringTab);
            app.ComparetwoCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComparetwoCheckBoxValueChanged, true);
            app.ComparetwoCheckBox.Text = 'Compare two?';
            app.NoticeLabel = uilabel(app.GameQualityvsScoringTab);
            app.NoticeLabel.Visible = 'off';
            app.NoticeLabel.Text = 'NOTICE: The Bills vs Bengals game was canceled on Week 17';
            app.SingleGameAnalysisTab = uitab(app.TabGroup);
            app.SingleGameAnalysisTab.Title = 'Single Game Analysis';
            app.Team1GameAxes = uiaxes(app.SingleGameAnalysisTab);
            title(app.Team1GameAxes, 'Title')
            ylabel(app.Team1GameAxes, 'ELO Rating')
            zlabel(app.Team1GameAxes, 'Z')
            app.Team2GameAxes = uiaxes(app.SingleGameAnalysisTab);
            title(app.Team2GameAxes, 'Title')
            ylabel(app.Team2GameAxes, 'ELO Rating')
            zlabel(app.Team2GameAxes, 'Z')
            app.PieChartAxes = uiaxes(app.SingleGameAnalysisTab);
            title(app.PieChartAxes, 'Title')
            ylabel(app.PieChartAxes, 'Y')
            zlabel(app.PieChartAxes, 'Z')
            app.SingleGameAnalysisLabel = uilabel(app.SingleGameAnalysisTab);
            app.SingleGameAnalysisLabel.Text = 'Single Game Analysis ';
            app.WeekDropDownLabel = uilabel(app.SingleGameAnalysisTab);
            app.WeekDropDownLabel.Text = 'Week';
            app.WeekDropDown = uidropdown(app.SingleGameAnalysisTab);
            app.WeekDropDown.ValueChangedFcn = createCallbackFcn(app, @WeekDropDownValueChanged, true);
            app.WeekGameDropDown = uidropdown(app.SingleGameAnalysisTab);
            app.WeekGameDropDown.ValueChangedFcn = createCallbackFcn(app, @WeekGameDropDownValueChanged, true);
            app.WeekLabel = uilabel(app.SingleGameAnalysisTab);
            app.WeekLabel.Text = 'Week 1';
            app.GameResultsLabel = uilabel(app.SingleGameAnalysisTab);
            app.GameResultsLabel.Text = 'Game Results';
            app.Team1NameLabel = uilabel(app.SingleGameAnalysisTab);
            app.Team1NameLabel.Text = 'Team 1 Name';
            app.Team2NameLabelSingleGame = uilabel(app.SingleGameAnalysisTab);
            app.Team2NameLabelSingleGame.Text = 'Team 2 Name';
            app.Result1Label = uilabel(app.SingleGameAnalysisTab);
            app.Result1Label.Text = 'Result 1';
            app.Result2Label = uilabel(app.SingleGameAnalysisTab);
            app.Result2Label.Text = 'Result 2';
            app.WeekSliderLabel = uilabel(app.SingleGameAnalysisTab);
            app.WeekSliderLabel.Text = 'Week Slider';
            app.WeekSlider = uislider(app.SingleGameAnalysisTab);
            app.WeekSlider.Limits = [1 22];
            app.WeekSlider.ValueChangedFcn = createCallbackFcn(app, @WeekSliderValueChanged, true);
            app.WeekSlider.Value = 1;
            app.GlossaryTab = uitab(app.TabGroup);
            app.GlossaryTab.Title = 'Glossary';
            app.GlossaryLabel = uilabel(app.GlossaryTab);
            app.GlossaryLabel.Text = 'Glossary';
            app.ELORatingLabel = uilabel(app.GlossaryTab);
            app.ELORatingLabel.Text = 'ELO Rating:';
            app.SourceCitationLabel = uilabel(app.GlossaryTab);
            app.SourceCitationLabel.Text = 'Boice, Joy. February 12, 2023. NFL-elo. Retrieved from https://github.com/fivethirtyeight/data/tree/master/nfl-elo#readme';
            app.SourcesLabel = uilabel(app.GlossaryTab);
            app.SourcesLabel.Text = 'Sources:';
            app.Label = uilabel(app.GlossaryTab);
            app.Label.Text = 'General power ratings a team and quarterback are assigned, which can help compute other aspects such as their win probability for a certain game';
            app.SourceCitationLabel_2 = uilabel(app.GlossaryTab);
            app.SourceCitationLabel_2.Text = 'Silver, Nate. “How Our NFL Predictions Work.” FiveThirtyEight, 9 Jan. 2023, fivethirtyeight.com/methodology/how-our-nfl-predictions-work.';
            app.ByeWeekLabel_2 = uilabel(app.GlossaryTab);
            app.ByeWeekLabel_2.Text = 'Bye Week:';
            app.Label_2 = uilabel(app.GlossaryTab);
            app.Label_2.Text = 'The week an NFL team is not considered to play in, which is important to consider when observing the data and charts within the app. ';
            app.WinPercentageLabel = uilabel(app.GlossaryTab);
            app.WinPercentageLabel.Text = 'Win Percentage:';
            app.Label_3 = uilabel(app.GlossaryTab);
            app.Label_3.Text = 'The percentages a team has to win prior to the game, which are based off of the ELO ratings of the team and their quarterback. ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NFL2022Analysis_Final_Version

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end